classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FiltersPanel       matlab.ui.container.Panel
        ToggleKnob         matlab.ui.control.DiscreteKnob
        ToggleKnobLabel    matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        PauseButton        matlab.ui.control.StateButton
        Switch             matlab.ui.control.Switch
        VolumeSlider       matlab.ui.control.Slider
        VolumeSliderLabel  matlab.ui.control.Label
        SongDropDown       matlab.ui.control.DropDown
        SongDropDownLabel  matlab.ui.control.Label
        StopButton         matlab.ui.control.Button
        PlayButton         matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fname % filename
        x % song data
        fs % sampling frequency
        blk_len = 2048 % block length 
        state % playback state
        h_line % time-plot line object 
        h_bar % freq-plot line object
        x_start = 1 % 
        change_detected % Description
    end
    
    properties (Access = public)
        volume = 50; % initial volume
    end
    
    methods (Access = private)
        
        function load_song(app)
          app.fname = app.SongDropDown.Value;
          [app.x, app.fs] = audioread(app.fname);
          app.x = mean(app.x, 2);      
          app.x = padarray(app.x, app.blk_len - rem(length(app.x),...
              app.blk_len), 0, 'post');
            
        end
        
        function play_song(app)
            msound('close');
            msound('openwrite', 0, app.fs, app.blk_len, 1);
            Y_bar = zeros(16, 1);
            wnd = hamming(app.blk_len);
            
            N = app.blk_len;
            k = (0:N/2);
            w = 2*pi*k/N;
            f = w*app.fs/(2*pi); 
            
            %% Filter design
        
            % Lowpass filter 
            fc1 = 200; 
            wc1 = 2*pi*fc1/app.fs;
            h_low = fir1(1024, wc1/pi, 'low');
            
            % Highpass filter
            fc2 = 1000;
            wc2 = 2*pi*fc2/app.fs;
            h_high = fir1(1024, wc2/pi, 'high');
            
            % Bandpass filter 
            h_band = fir1(1024, [wc1/pi, wc2/pi], 'band');
            
            % Best filter (music playing in another room)
            h_best = h_low * 2 + h_band * 0.5;

            try
                for idx = app.x_start:app.blk_len:length(app.x)
                    if(app.ToggleKnob.Value == "Lowpass")
                        h = h_low;
                    elseif(app.ToggleKnob.Value == "Highpass")
                        h = h_high;
                    elseif(app.ToggleKnob.Value == "Bandpass")
                        h = h_band;
                    elseif(app.ToggleKnob.Value == "Bestpass")
                        h = h_best;
                    end
                    
                    if(app.ToggleKnob.Value ~= "Allpass")
                        h = transpose(h);
                        h_len1 = length(app.x);
                        h_len2 = length(h);
                        N = h_len1 + h_len2 - 1;

                        X = fft(app.x, N);
                        H = fft(h, N);
                        Y = X.*H;
                        y = ifft(Y);
                        y = y(1:h_len1);
                    else
                        y = app.x;
                    end
                    
                    if(app.state == 2)
                        app.x_start = idx;
                        break;
                    elseif(app.state == 0)
                        app.x_start = 1;
                        break;
                    end
                    if(app.ToggleKnob.Value == "Allpass")
                        y_blk = app.x(idx:idx+app.blk_len-1, :);
                    elseif(app.ToggleKnob.Value ~= "Allpass")
                        y_blk = y(idx:idx+app.blk_len-1, :);
                    end
                    
                    msound('putsamples', app.VolumeSlider.Value / 50 * y_blk);
                    
                    Y_blk = fft(y_blk.*wnd);
                    Y_blk_half = abs(Y_blk(1:1024));
                    Y_bar(16) = mean(Y_blk_half(960:1024));
                    Y_bar(15) = mean(Y_blk_half(896:959));
                    Y_bar(14) = mean(Y_blk_half(832:895));
                    Y_bar(13) = mean(Y_blk_half(768:831));
                    Y_bar(12) = mean(Y_blk_half(704:767));
                    Y_bar(11) = mean(Y_blk_half(640:703));
                    Y_bar(10) = mean(Y_blk_half(576:639));
                    Y_bar(9) = mean(Y_blk_half(512:575));
                    Y_bar(8) = mean(Y_blk_half(448:511));
                    Y_bar(7) = mean(Y_blk_half(384:447));
                    Y_bar(6) = mean(Y_blk_half(320:383));
                    Y_bar(5) = mean(Y_blk_half(256:319));
                    Y_bar(4) = mean(Y_blk_half(192:255));
                    Y_bar(3) = mean(Y_blk_half(128:191));
                    Y_bar(2) = mean(Y_blk_half(64:127));
                    Y_bar(1) = mean(Y_blk_half(20:63));
                    
                    if(app.Switch.Value == "Freq")
                        app.h_bar = bar(app.UIAxes, ...
                            (1:16), 20*log10(Y_bar), ...
                            'FaceColor', [252, 180, 172]/255, ...
                            'LineStyle', 'none', ...
                            'EraseMode', 'none' ...
                            );
                        set(app.UIAxes, ...
                            'XTick', [], 'XTickMode', 'manual',...
                            'YTick', [], 'YTickMode', 'manual',...
                            'XLim', [0, 15], 'XLimMode', 'manual',...
                            'YLim', [0, 50], 'YLimMode', 'manual'...
                            );
                        % app.h_bar
                        app.h_bar.YData = 20*log10(Y_bar);
                    elseif(app.Switch.Value == "Time")
                        cla(app.UIAxes)
                        app.h_line = line(app.UIAxes,...
                            'XData', 0:app.blk_len-1,...
                            'YData', y_blk,...
                            'Color', [252, 180, 172]/255,...
                            'LineWidth', 1,...
                            'EraseMode', 'xor'...
                            );
                        set(app.UIAxes,...
                            'XTick', [], 'XTickMode', 'manual',...
                            'YTick', [], 'YTickMode', 'manual',...
                            'XLim', [0, app.blk_len-1], 'XLimMode', 'manual',...
                            'YLim', [-1, 1], 'YLimMode', 'manual'...
                            );
                        app.h_line.YData = y_blk;
                    end
                    drawnow limitrate;
                end
            catch e
                msound('close');
                rethrow(e);
            end
            msound('close');
        end
        function stop_song(app)
            app.state = 0;
        end
        function pause_song(app)
            app.state = 2;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
    
            msound('close')
            load_song(app)
            app.state = 1;
            disp('play button clicked')
            play_song(app)
          
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            disp('stop button clicked')
            msound('close')
            stop_song(app)
            play_song(app)
            cla(app.UIAxes)
            
        end

        % Value changed function: PauseButton
        function PauseButtonPushed(app, event)
            
            pause_song(app)
            app.state = 2;
            disp('pause button clicked')
            
        end

        % Value changing function: VolumeSlider
        function VolumeSliderValueChanging(app, event)
            
            app.volume = app.VolumeSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.PlayButton = uibutton(app.Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SongDropDownLabel = uilabel(app.Panel);
            app.SongDropDownLabel.Text = 'Song';
            app.SongDropDown = uidropdown(app.Panel);
            app.SongDropDown.Items = {'GerudoPeeples.wav', 'AreWeStilFriends2.wav', 'LessOfYou.wav', ''};
            app.SongDropDown.Value = 'GerudoPeeples.wav';
            app.VolumeSliderLabel = uilabel(app.Panel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.Panel);
            app.VolumeSlider.MajorTicks = [0 50 100];
            app.VolumeSlider.ValueChangingFcn = createCallbackFcn(app, @VolumeSliderValueChanging, true);
            app.VolumeSlider.Value = 50;
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.Items = {'Time', 'Freq'};
            app.Switch.Value = 'Time';
            app.PauseButton = uibutton(app.Panel, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.FiltersPanel = uipanel(app.UIFigure);
            app.FiltersPanel.Title = 'Filters';
            app.ToggleKnobLabel = uilabel(app.FiltersPanel);
            app.ToggleKnobLabel.Text = 'Toggle';
            app.ToggleKnob = uiknob(app.FiltersPanel, 'discrete');
            app.ToggleKnob.Items = {'Allpass', 'Lowpass', 'Highpass', 'Bandpass', 'Bestpass', ''};
            app.ToggleKnob.Value = 'Allpass';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end